classdef timeHistoryPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimeHistoryPlotterUIFigure     matlab.ui.Figure
        DataFilesMenu                  matlab.ui.container.Menu
        LoadMenu_2                     matlab.ui.container.Menu
        LoadDemoDataMenu               matlab.ui.container.Menu
        TemplatesMenu                  matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        disptoplinesoftimehistorydatasetLabel  matlab.ui.control.Label
        TmpTree                        matlab.ui.container.Tree
        TimeHistoryDataFilesPanel      matlab.ui.container.Panel
        PreviewDataButton              matlab.ui.control.Button
        LoadedFilesListBox             matlab.ui.control.ListBox
        DeleteButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        DataChannelswithinselectedfilePanel  matlab.ui.container.Panel
        SearchDataChannelsEditField    matlab.ui.control.EditField
        SearchEditFieldLabel           matlab.ui.control.Label
        DataChannelsListBox            matlab.ui.control.ListBox
        LinePropertiesPanel            matlab.ui.container.Panel
        ratelimiterLabel               matlab.ui.control.Label
        errorbarLabel                  matlab.ui.control.Label
        UnitsDropDown                  matlab.ui.control.DropDown
        UnitsDropDownLabel             matlab.ui.control.Label
        BiastoZeroLineCheckBox         matlab.ui.control.CheckBox
        DifferentiateLineCheckBox      matlab.ui.control.CheckBox
        IntegrateLineCheckBox          matlab.ui.control.CheckBox
        yyaxisRightLineCheckBox        matlab.ui.control.CheckBox
        DontShowLineCheckBox           matlab.ui.control.CheckBox
        MinCheckBox                    matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        LineStyleTab                   matlab.ui.container.Tab
        MarkerSizeDropDown             matlab.ui.control.DropDown
        MarkerSizeDropDownLabel        matlab.ui.control.Label
        StyleDropDown                  matlab.ui.control.DropDown
        StyleDropDownLabel             matlab.ui.control.Label
        MarkerDropDown                 matlab.ui.control.DropDown
        MarkerDropDownLabel            matlab.ui.control.Label
        LineWidthDropDown              matlab.ui.control.DropDown
        LineWidthDropDownLabel         matlab.ui.control.Label
        ColorDropDown                  matlab.ui.control.DropDown
        ColorDropDownLabel             matlab.ui.control.Label
        FiltersTab                     matlab.ui.container.Tab
        ApplyFilterLineCheckBox        matlab.ui.control.CheckBox
        ApplySmoothDataLineCheckBox    matlab.ui.control.CheckBox
        FilterbLineEditField           matlab.ui.control.EditField
        FilterbEditFieldLabel          matlab.ui.control.Label
        FilteraLineEditField           matlab.ui.control.EditField
        FilteraEditFieldLabel          matlab.ui.control.Label
        WindowLineEditField            matlab.ui.control.EditField
        SmoothDataWindowLabel          matlab.ui.control.Label
        MethodLineDropDown             matlab.ui.control.DropDown
        SmoothDataMethodLabel          matlab.ui.control.Label
        MaxCheckBox                    matlab.ui.control.CheckBox
        AxisPropertiesPanel            matlab.ui.container.Panel
        AxisNameasylabelCheckBox       matlab.ui.control.CheckBox
        HorizontalLinesEditField       matlab.ui.control.EditField
        HorizontalLinesEditFieldLabel  matlab.ui.control.Label
        LegendTitleEditField           matlab.ui.control.EditField
        LegendTitleEditFieldLabel      matlab.ui.control.Label
        logyCheckBox                   matlab.ui.control.CheckBox
        ymaxEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        yminEditFieldLabel             matlab.ui.control.Label
        DontShowAxisCheckBox           matlab.ui.control.CheckBox
        LegendLocationDropDown         matlab.ui.control.DropDown
        LegendLocationDropDownLabel    matlab.ui.control.Label
        TightCheckBox                  matlab.ui.control.CheckBox
        FigurePropertiesPanel          matlab.ui.container.Panel
        DateTimeFormatEditField        matlab.ui.control.EditField
        DateTimeFormatEditFieldLabel   matlab.ui.control.Label
        TextInterpreterDropDown        matlab.ui.control.DropDown
        TextInterpreterDropDownLabel   matlab.ui.control.Label
        TemplateNameinsgtitleCheckBox  matlab.ui.control.CheckBox
        sgtitleEditField               matlab.ui.control.EditField
        sgtitleEditFieldLabel          matlab.ui.control.Label
        PostxlabeltextEditField        matlab.ui.control.EditField
        PostxlabeltextEditFieldLabel   matlab.ui.control.Label
        VerticalLinesEditField         matlab.ui.control.EditField
        VerticalLinesEditFieldLabel    matlab.ui.control.Label
        TimeMaxEditField               matlab.ui.control.NumericEditField
        TimeMaxEditFieldLabel          matlab.ui.control.Label
        TimeMinEditField               matlab.ui.control.NumericEditField
        TimeMinEditFieldLabel          matlab.ui.control.Label
        logxCheckBox                   matlab.ui.control.CheckBox
        TileSpaceDropDown              matlab.ui.control.DropDown
        TileSpaceDropDownLabel         matlab.ui.control.Label
        BoldCheckBox                   matlab.ui.control.CheckBox
        FigureNumDropDown              matlab.ui.control.DropDown
        FigureDropDownLabel            matlab.ui.control.Label
        LinkAxesCheckBox               matlab.ui.control.CheckBox
        FileNameinsgtitleCheckBox      matlab.ui.control.CheckBox
        FigureSizeDropDown             matlab.ui.control.DropDown
        FigureSizeLabel                matlab.ui.control.Label
        MinorGridCheckBox              matlab.ui.control.CheckBox
        IndependentChannelDropDown     matlab.ui.control.DropDown
        IndependentChannelDropDownLabel  matlab.ui.control.Label
        MajorGridCheckBox              matlab.ui.control.CheckBox
        PrintButton                    matlab.ui.control.Button
        keyboardButton                 matlab.ui.control.Button
        clcButton                      matlab.ui.control.Button
        CloseallButton                 matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        ContextMenuTmpTree             matlab.ui.container.ContextMenu
        ExpandTemplateMenu             matlab.ui.container.Menu
        TemplatesMenu_2                matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        DeleteMenu                     matlab.ui.container.Menu
        AxisMenu                       matlab.ui.container.Menu
        NewMenu_2                      matlab.ui.container.Menu
        DeleteMenu_2                   matlab.ui.container.Menu
        LineMenu                       matlab.ui.container.Menu
        NewMenu_3                      matlab.ui.container.Menu
        DeleteMenu_3                   matlab.ui.container.Menu
        ContextMenuDataChannels        matlab.ui.container.ContextMenu
        AddtocurrentAxisoverwriteMenu  matlab.ui.container.Menu
        AddtocurrentAxisnewlineMenu    matlab.ui.container.Menu
        AddtoNEWAxisMenu               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        tHData      % All time history data
        % templates   % All templates
        mapping
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
            clc
            % Setup gui for use
            guiControl.onStartUp(app);


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            dataLoader.topLevel(app);
        end

        % Value changed function: LoadedFilesListBox
        function LoadedFilesListBoxValueChanged(app, event)
            guiControl.listBoxLoadedFilesChanged(app);
        end

        % Button pushed function: clcButton
        function clcButtonPushed(app, event)
            clc
        end

        % Drop down opening function: IndependentChannelDropDown
        function IndependentChannelDropDownOpening(app, event)
            guiControl.independentChannel_DropDownOpen(app);
        end

        % Button pushed function: keyboardButton
        function keyboardButtonPushed(app, event)
            keyboard
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            doPrint = true;
            guiControl.gatherTemplateIntoStruct(app, doPrint);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotting.topLevel(app);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            guiControl.deleteFile(app);
        end

        % Value changing function: SearchDataChannelsEditField
        function SearchDataChannelsEditFieldValueChanging(app, event)
            guiControl.searchDataChannelNames(app, event.Value);
        end

        % Menu selected function: LoadDemoDataMenu
        function LoadDemoDataMenuSelected(app, event)
            dataLoader.loadDemoData(app);
        end

        % Close request function: TimeHistoryPlotterUIFigure
        function TimeHistoryPlotterUIFigureCloseRequest(app, event)
            
            % selection = uiconfirm(app.TimeHistoryPlotterUIFigure, ...
            %     "Close ""Time History Plotter""", "Confirm Close", ...
            %     "Options",["Close","Cancel"], ...
            %     "Icon","warning");
            % switch selection
            %     case {'Close'}
                    delete(app)
            %     case {'Cancel'}
            % end
            
        end

        % Selection changed function: TmpTree
        function TmpTreeSelectionChanged(app, event)
            % selectedNodes = app.TmpTree.SelectedNodes;
            guiControl.treeSelectionChanged(app, event.SelectedNodes, event.PreviousSelectedNodes);
        end

        % Menu selected function: NewMenu
        function treeMenuTempNew(app, event)
            guiControl.makeNewTemplate(app);
        end

        % Menu selected function: DeleteMenu
        function treeMenuTempDelete(app, event)
            guiControl.deleteTemplate(app);
        end

        % Menu selected function: NewMenu_2
        function treeMenuAxisNew(app, event)
            guiControl.makeNewAxis(app);
        end

        % Menu selected function: DeleteMenu_2
        function treeMenuAxisDelete(app, event)
            guiControl.deleteAxis(app);
        end

        % Menu selected function: NewMenu_3
        function TreeMenuLineNew(app, event)
            guiControl.makeNewLine(app);
        end

        % Menu selected function: DeleteMenu_3
        function TreeMenuLineDelete(app, event)
            guiControl.deleteLine(app);
        end

        % Callback function
        function dataChanMenu_AddCurrent(app, event)
            
        end

        % Menu selected function: ExpandTemplateMenu
        function expandTemplateCB(app, event)
            guiControl.expandTemplate(app);
        end

        % Callback function
        function runMapping(app, event)
            guiControl.defineMapping(app);
        end

        % Menu selected function: AddtocurrentAxisoverwriteMenu
        function dataChanMenu_overWrite(app, event)
            optUseCurAxis   = true;
            optOverWrite    = true;
            guiControl.addCurrentToCurrentAxis(app, optUseCurAxis, optOverWrite);
        end

        % Menu selected function: AddtocurrentAxisnewlineMenu
        function dataChanMenu_newLine(app, event)
            optUseCurAxis   = true;
            optOverWrite    = false;
            guiControl.addCurrentToCurrentAxis(app, optUseCurAxis, optOverWrite);
        end

        % Menu selected function: AddtoNEWAxisMenu
        function dataChanMenu_newAxis(app, event)
            optUseCurAxis   = false;
            optOverWrite    = false;
            guiControl.addCurrentToCurrentAxis(app, optUseCurAxis, optOverWrite);
        end

        % Button pushed function: PreviewDataButton
        function PreviewLoadedTHData(app, event)
            dataLoader.previewData(app);
        end

        % Menu selected function: LoadMenu
        function MenuLoadTemplateSelected(app, event)
            templateLoader.loadFromFile(app);
        end

        % Menu selected function: SaveMenu
        function SaveTemplateSelected(app, event)
            templateLoader.saveToFile(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimeHistoryPlotterUIFigure = uifigure('Visible', 'off');
            app.TimeHistoryPlotterUIFigure.Name = 'Time History Plotter';
            app.TimeHistoryPlotterUIFigure.CloseRequestFcn = createCallbackFcn(app, @TimeHistoryPlotterUIFigureCloseRequest, true);
            app.DataFilesMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.DataFilesMenu.Text = 'Data Files';
            app.LoadMenu_2 = uimenu(app.DataFilesMenu);
            app.LoadMenu_2.Text = 'Load';
            app.LoadDemoDataMenu = uimenu(app.DataFilesMenu);
            app.LoadDemoDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDemoDataMenuSelected, true);
            app.LoadDemoDataMenu.Text = 'Load Demo Data';
            app.TemplatesMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.TemplatesMenu.Text = 'Templates';
            app.LoadMenu = uimenu(app.TemplatesMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuLoadTemplateSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.TemplatesMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveTemplateSelected, true);
            app.SaveMenu.Text = 'Save';
            app.PlotButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CloseallButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.clcButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.clcButton.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clcButton.Text = 'clc';
            app.keyboardButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.keyboardButton.ButtonPushedFcn = createCallbackFcn(app, @keyboardButtonPushed, true);
            app.keyboardButton.Text = 'keyboard';
            app.PrintButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.FigurePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.FigurePropertiesPanel.Title = 'Figure Properties';
            app.MajorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MajorGridCheckBox.Text = 'Major Grid';
            app.IndependentChannelDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.IndependentChannelDropDownLabel.Text = 'Independent Channel';
            app.IndependentChannelDropDown = uidropdown(app.FigurePropertiesPanel);
            app.IndependentChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IndependentChannelDropDownOpening, true);
            app.MinorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MinorGridCheckBox.Text = 'Minor Grid';
            app.FigureSizeLabel = uilabel(app.FigurePropertiesPanel);
            app.FigureSizeLabel.Text = 'Figure Size';
            app.FigureSizeDropDown = uidropdown(app.FigurePropertiesPanel);
            app.FigureSizeDropDown.Items = {'Dont change', '560, 600 - small portrait', '740, 790 - medium portrait', '890, 900 - portrait', '1100, 650 - small landscape', '1240, 800 - medium landscape', '1585, 960 - landscape'};
            app.FigureSizeDropDown.Value = 'Dont change';
            app.FileNameinsgtitleCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.FileNameinsgtitleCheckBox.Text = 'File Name in sgtitle';
            app.LinkAxesCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.FigureDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.FigureDropDownLabel.Text = 'Figure';
            app.FigureNumDropDown = uidropdown(app.FigurePropertiesPanel);
            app.FigureNumDropDown.Items = {'gcf', 'figure', 'figure(1)', 'figure(2)', 'figure(3)', 'figure(4)', 'figure(5)', 'figure(6)', 'figure(7)', 'figure(8)', 'figure(9)'};
            app.FigureNumDropDown.Value = 'gcf';
            app.BoldCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.BoldCheckBox.Text = 'Bold';
            app.TileSpaceDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TileSpaceDropDownLabel.Text = 'Tile Space';
            app.TileSpaceDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TileSpaceDropDown.Items = {'loose', 'compact', 'tight', 'none'};
            app.TileSpaceDropDown.Value = 'loose';
            app.logxCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.logxCheckBox.Text = 'logx';
            app.TimeMinEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.TimeMinEditFieldLabel.Text = 'Time Min';
            app.TimeMinEditField = uieditfield(app.FigurePropertiesPanel, 'numeric');
            app.TimeMinEditField.Value = -Inf;
            app.TimeMaxEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.TimeMaxEditFieldLabel.Text = 'Time Max';
            app.TimeMaxEditField = uieditfield(app.FigurePropertiesPanel, 'numeric');
            app.TimeMaxEditField.Value = Inf;
            app.VerticalLinesEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.VerticalLinesEditFieldLabel.Text = 'Vertical Lines';
            app.VerticalLinesEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.PostxlabeltextEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.PostxlabeltextEditFieldLabel.Text = 'Post xlabel text';
            app.PostxlabeltextEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.sgtitleEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.sgtitleEditFieldLabel.Text = 'sgtitle';
            app.sgtitleEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.TemplateNameinsgtitleCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.TemplateNameinsgtitleCheckBox.Text = 'Template Name in sgtitle';
            app.TextInterpreterDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TextInterpreterDropDownLabel.Text = 'Text Interpreter';
            app.TextInterpreterDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TextInterpreterDropDown.Items = {'none', 'tex'};
            app.TextInterpreterDropDown.Editable = 'on';
            app.TextInterpreterDropDown.Value = 'none';
            app.DateTimeFormatEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.DateTimeFormatEditFieldLabel.Text = 'Date Time Format';
            app.DateTimeFormatEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.AxisPropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.AxisPropertiesPanel.Enable = 'off';
            app.AxisPropertiesPanel.Title = 'Axis Properties';
            app.TightCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.TightCheckBox.Text = 'Tight';
            app.LegendLocationDropDownLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.AxisPropertiesPanel);
            app.LegendLocationDropDown.Items = {'best', 'northeast', 'northwest', 'north', 'south', 'east', 'west', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'bestoutside', 'none'};
            app.LegendLocationDropDown.Value = 'best';
            app.DontShowAxisCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.DontShowAxisCheckBox.Text = 'Dont Show';
            app.yminEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.AxisPropertiesPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.AxisPropertiesPanel, 'numeric');
            app.logyCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.logyCheckBox.Text = 'logy';
            app.LegendTitleEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendTitleEditFieldLabel.Text = 'Legend Title';
            app.LegendTitleEditField = uieditfield(app.AxisPropertiesPanel, 'text');
            app.HorizontalLinesEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.HorizontalLinesEditFieldLabel.Text = 'Horizontal Lines';
            app.HorizontalLinesEditField = uieditfield(app.AxisPropertiesPanel, 'text');
            app.AxisNameasylabelCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.AxisNameasylabelCheckBox.Text = 'Axis Name as ylabel';
            app.LinePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.LinePropertiesPanel.Enable = 'off';
            app.LinePropertiesPanel.Title = 'Line Properties';
            app.MaxCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MaxCheckBox.Text = 'Max';
            app.TabGroup = uitabgroup(app.LinePropertiesPanel);
            app.LineStyleTab = uitab(app.TabGroup);
            app.LineStyleTab.Title = 'Line Style';
            app.ColorDropDownLabel = uilabel(app.LineStyleTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LineStyleTab);
            app.ColorDropDown.Items = {'automatic', 'black', 'blue', 'orange', 'yellow', 'purple', 'green', 'light blue', 'red', 'magenta'};
            app.ColorDropDown.Value = 'automatic';
            app.LineWidthDropDownLabel = uilabel(app.LineStyleTab);
            app.LineWidthDropDownLabel.Text = 'Line Width';
            app.LineWidthDropDown = uidropdown(app.LineStyleTab);
            app.LineWidthDropDown.Items = {'0.1', '0.25', '0.5', '0.75', '1.0', '1.5', '2.0', '2.5', '3.0', '4.0', '5.0', '6.0'};
            app.LineWidthDropDown.Value = '0.1';
            app.MarkerDropDownLabel = uilabel(app.LineStyleTab);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.LineStyleTab);
            app.MarkerDropDown.Items = {'none', 'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MarkerDropDown.Value = 'none';
            app.StyleDropDownLabel = uilabel(app.LineStyleTab);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.LineStyleTab);
            app.StyleDropDown.Items = {'-', ':', '-.', '--', 'none'};
            app.StyleDropDown.Value = '-';
            app.MarkerSizeDropDownLabel = uilabel(app.LineStyleTab);
            app.MarkerSizeDropDownLabel.Text = 'Marker Size';
            app.MarkerSizeDropDown = uidropdown(app.LineStyleTab);
            app.MarkerSizeDropDown.Items = {'2', '4', '5', '6', '7', '8', '10', '12', '14', '16', '18', '20', '25', '30', '35', '40'};
            app.MarkerSizeDropDown.Value = '6';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.SmoothDataMethodLabel = uilabel(app.FiltersTab);
            app.SmoothDataMethodLabel.Text = 'Method';
            app.MethodLineDropDown = uidropdown(app.FiltersTab);
            app.MethodLineDropDown.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.MethodLineDropDown.Value = 'movmean';
            app.SmoothDataWindowLabel = uilabel(app.FiltersTab);
            app.SmoothDataWindowLabel.Text = 'Window';
            app.WindowLineEditField = uieditfield(app.FiltersTab, 'text');
            app.WindowLineEditField.Value = '4';
            app.FilteraEditFieldLabel = uilabel(app.FiltersTab);
            app.FilteraEditFieldLabel.Text = '"Filter" a';
            app.FilteraLineEditField = uieditfield(app.FiltersTab, 'text');
            app.FilteraLineEditField.Value = '1';
            app.FilterbEditFieldLabel = uilabel(app.FiltersTab);
            app.FilterbEditFieldLabel.Text = '"Filter" b';
            app.FilterbLineEditField = uieditfield(app.FiltersTab, 'text');
            app.FilterbLineEditField.Value = '0.2 0.2 0.2 0.2 0.2';
            app.ApplySmoothDataLineCheckBox = uicheckbox(app.FiltersTab);
            app.ApplySmoothDataLineCheckBox.Text = 'Apply "SmoothData"';
            app.ApplyFilterLineCheckBox = uicheckbox(app.FiltersTab);
            app.ApplyFilterLineCheckBox.Text = 'Apply "Filter"';
            app.MinCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MinCheckBox.Text = 'Min';
            app.DontShowLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DontShowLineCheckBox.Text = 'Dont Show';
            app.yyaxisRightLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.yyaxisRightLineCheckBox.Text = 'yyaxis right';
            app.IntegrateLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.IntegrateLineCheckBox.Text = 'Integrate';
            app.DifferentiateLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DifferentiateLineCheckBox.Text = 'Differentiate';
            app.BiastoZeroLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.BiastoZeroLineCheckBox.Text = 'Bias to Zero';
            app.UnitsDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.UnitsDropDownLabel.Enable = 'off';
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.LinePropertiesPanel);
            app.UnitsDropDown.Editable = 'on';
            app.UnitsDropDown.Enable = 'off';
            app.errorbarLabel = uilabel(app.LinePropertiesPanel);
            app.errorbarLabel.Text = 'errorbar';
            app.ratelimiterLabel = uilabel(app.LinePropertiesPanel);
            app.ratelimiterLabel.Text = 'rate limiter';
            app.DataChannelswithinselectedfilePanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.DataChannelswithinselectedfilePanel.Title = 'Data Channels - within selected file';
            app.DataChannelsListBox = uilistbox(app.DataChannelswithinselectedfilePanel);
            app.DataChannelsListBox.Multiselect = 'on';
            app.DataChannelsListBox.Value = {'Item 1'};
            app.SearchEditFieldLabel = uilabel(app.DataChannelswithinselectedfilePanel);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchDataChannelsEditField = uieditfield(app.DataChannelswithinselectedfilePanel, 'text');
            app.SearchDataChannelsEditField.ValueChangingFcn = createCallbackFcn(app, @SearchDataChannelsEditFieldValueChanging, true);
            app.TimeHistoryDataFilesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.TimeHistoryDataFilesPanel.Title = 'Time History Data Files';
            app.LoadButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.LoadedFilesListBox = uilistbox(app.TimeHistoryDataFilesPanel);
            app.LoadedFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedFilesListBoxValueChanged, true);
            app.PreviewDataButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.PreviewDataButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewLoadedTHData, true);
            app.PreviewDataButton.Text = 'Preview Data';
            app.TmpTree = uitree(app.TimeHistoryPlotterUIFigure);
            app.TmpTree.SelectionChangedFcn = createCallbackFcn(app, @TmpTreeSelectionChanged, true);
            app.TmpTree.Editable = 'on';
            app.disptoplinesoftimehistorydatasetLabel = uilabel(app.TimeHistoryPlotterUIFigure);
            app.disptoplinesoftimehistorydatasetLabel.Text = {'ToDo:'; '>> disp() top lines of time history data set'; '>> error bars and rate limiter'; '>> add channels to new template one per axis'; '>> Reorder lines axes maybe templates'; '>> load/save template'; '>> bug - add to axis new line adds same '; '>> delete file list bos bug'};
            app.ContextMenuTmpTree = uicontextmenu(app.TimeHistoryPlotterUIFigure);
            app.ExpandTemplateMenu = uimenu(app.ContextMenuTmpTree);
            app.ExpandTemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @expandTemplateCB, true);
            app.ExpandTemplateMenu.Text = 'Expand Template';
            app.TemplatesMenu_2 = uimenu(app.ContextMenuTmpTree);
            app.TemplatesMenu_2.Text = 'Templates';
            app.NewMenu = uimenu(app.TemplatesMenu_2);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @treeMenuTempNew, true);
            app.NewMenu.Text = 'New';
            app.DeleteMenu = uimenu(app.TemplatesMenu_2);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @treeMenuTempDelete, true);
            app.DeleteMenu.Text = 'Delete';
            app.AxisMenu = uimenu(app.ContextMenuTmpTree);
            app.AxisMenu.Text = 'Axis';
            app.NewMenu_2 = uimenu(app.AxisMenu);
            app.NewMenu_2.MenuSelectedFcn = createCallbackFcn(app, @treeMenuAxisNew, true);
            app.NewMenu_2.Text = 'New';
            app.DeleteMenu_2 = uimenu(app.AxisMenu);
            app.DeleteMenu_2.MenuSelectedFcn = createCallbackFcn(app, @treeMenuAxisDelete, true);
            app.DeleteMenu_2.Text = 'Delete';
            app.LineMenu = uimenu(app.ContextMenuTmpTree);
            app.LineMenu.Text = 'Line';
            app.NewMenu_3 = uimenu(app.LineMenu);
            app.NewMenu_3.MenuSelectedFcn = createCallbackFcn(app, @TreeMenuLineNew, true);
            app.NewMenu_3.Text = 'New';
            app.DeleteMenu_3 = uimenu(app.LineMenu);
            app.DeleteMenu_3.MenuSelectedFcn = createCallbackFcn(app, @TreeMenuLineDelete, true);
            app.DeleteMenu_3.Text = 'Delete';
            % Assign app.ContextMenuTmpTree
            app.TmpTree.ContextMenu = app.ContextMenuTmpTree;
            app.ContextMenuDataChannels = uicontextmenu(app.TimeHistoryPlotterUIFigure);
            app.AddtocurrentAxisoverwriteMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtocurrentAxisoverwriteMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_overWrite, true);
            app.AddtocurrentAxisoverwriteMenu.Text = 'Add to current Axis (overwrite)';
            app.AddtocurrentAxisnewlineMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtocurrentAxisnewlineMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_newLine, true);
            app.AddtocurrentAxisnewlineMenu.Text = 'Add to current Axis (new line)';
            app.AddtoNEWAxisMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtoNEWAxisMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_newAxis, true);
            app.AddtoNEWAxisMenu.Text = 'Add to NEW Axis';
            % Assign app.ContextMenuDataChannels
            app.DataChannelsListBox.ContextMenu = app.ContextMenuDataChannels;
            % Show the figure after all components are created
            app.TimeHistoryPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeHistoryPlotter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TimeHistoryPlotterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TimeHistoryPlotterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimeHistoryPlotterUIFigure)
        end
    end
end